classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectDataSetButtonGroup        matlab.ui.container.ButtonGroup
        ScenarioAPatientInfoButton      matlab.ui.control.RadioButton
        ScenarioBPatientInfoAdditionalInfoButton  matlab.ui.control.RadioButton
        ScenarioCPatientInfoAdditionalInfoButton  matlab.ui.control.RadioButton
        ExperimentalConfigurationPanel  matlab.ui.container.Panel
        NumRunsLabel                    matlab.ui.control.Label
        NumRunsEditField                matlab.ui.control.EditField
        TrainingRatioEditFieldLabel     matlab.ui.control.Label
        TrainingRatioEditField          matlab.ui.control.EditField
        FeatureSelectionPanel           matlab.ui.container.Panel
        KruskalWalliswThresholdCheckBox  matlab.ui.control.CheckBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        RemoveRedundancyCheckBox        matlab.ui.control.CheckBox
        ThresholdEditField_3Label       matlab.ui.control.Label
        ThresholdEditField_2            matlab.ui.control.EditField
        FeatureReductionPanel           matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PCAeigenvalueswkaisercriteriaButton  matlab.ui.control.RadioButton
        PCAeigenvalueswscreecriteriaButton  matlab.ui.control.RadioButton
        LDAButton                       matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        ClassifierPanel                 matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        EuclideanMinimumDistanceClassifierButton  matlab.ui.control.RadioButton
        FisherLDAClassifierButton       matlab.ui.control.RadioButton
        KNearestNeighborClassifierButton  matlab.ui.control.RadioButton
        SuportVectorMachineClassifierButton  matlab.ui.control.RadioButton
        BayesClassifierButton           matlab.ui.control.RadioButton
        KernelFunctionButtonGroup       matlab.ui.container.ButtonGroup
        linearButton                    matlab.ui.control.RadioButton
        polynomialButton                matlab.ui.control.RadioButton
        rbfgaussianButton               matlab.ui.control.RadioButton
        sigmoidalButton                 matlab.ui.control.RadioButton
        KernelFunctionButtonGroup_3     matlab.ui.container.ButtonGroup
        linearButton_3                  matlab.ui.control.RadioButton
        polynomialButton_3              matlab.ui.control.RadioButton
        rbfgaussianButton_3             matlab.ui.control.RadioButton
        sigmoidalButton_3               matlab.ui.control.RadioButton
        ParametersButtonGroup           matlab.ui.container.ButtonGroup
        CostpotLabel                    matlab.ui.control.Label
        CostpotEditField                matlab.ui.control.EditField
        GammapotLabel                   matlab.ui.control.Label
        GammapotEditField               matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        NeighboursLabel                 matlab.ui.control.Label
        NeighboursEditField             matlab.ui.control.EditField
        Panel                           matlab.ui.container.Panel
        Covid19PredictionLabel          matlab.ui.control.Label
        ResultsLabel                    matlab.ui.control.Label
        AvgSpecificityLabel             matlab.ui.control.Label
        AvgSensibilityLabel             matlab.ui.control.Label
        AvgAccuracyLabel                matlab.ui.control.Label
        AvgErrorLabel                   matlab.ui.control.Label
        AUCLabel                        matlab.ui.control.Label
        SpecResult                      matlab.ui.control.Label
        SensResult                      matlab.ui.control.Label
        AccResult                       matlab.ui.control.Label
        ErrorResult                     matlab.ui.control.Label
        AUCResult                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Default values (only needed for some):
            scenario = '';
            n_runs = 1;
            trn_ratio = 0.5;
            kw_check = 0;
            kw_thrshold = 0;
            corr_check = 0;
            corr_threshold = 0;
            pca_rule = '';
            feature_reduction = '';
            classifier = '';
            options = {};
            k_neighbours = 0;
            c_pot = 0;
            g_pot = 0;
            kernel = '';
            
            
            if strcmp(app.SelectDataSetButtonGroup.SelectedObject.Text, 'Scenario A (Patient Info)') == 1
                scenario = 'A';
            elseif strcmp(app.SelectDataSetButtonGroup.SelectedObject.Text, 'Scenario B (Patient Info + Additional Info)') == 1
                scenario = 'B';
            elseif strcmp(app.SelectDataSetButtonGroup.SelectedObject.Text, 'Scenario C (Patient Info + Additional Info)') == 1
                scenario = 'C';
            end
            
            n_runs = str2num(app.NumRunsEditField.Value);
            trn_ratio = str2double(app.TrainingRatioEditField.Value);
            
            % FEATURE REDUCTION
            kw_check = app.KruskalWalliswThresholdCheckBox.Value;
            kw_threshold = str2num(app.ThresholdEditField.Value);
            
            corr_check = app.RemoveRedundancyCheckBox.Value;
            corr_threshold = str2double(app.ThresholdEditField_2.Value);
            
            % FEATURE SELECTION
            if strcmp(app.ButtonGroup.SelectedObject.Text, 'PCA (eigen values w/ kaiser criteria)') == 1
                pca_rule = 'kaiser';
                feature_reduction = 'pca';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'PCA (eigen values w/ scree criteria)') == 1
                pca_rule = 'scree';
                feature_reduction = 'pca';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'LDA') == 1
                feature_reduction = 'lda';
            elseif strcmp(app.ButtonGroup.SelectedObject.Text, 'none') == 1
                feature_reduction = 'none';
            end
            
            % CLASSIFIER
            if strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Euclidean Minimum Distance Classifier') == 1
                classifier = 'mdc';
                
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Fisher LDA Classifier') == 1
                classifier = 'fld';
                
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'K Nearest Neighbor Classifier') == 1
                classifier = 'knn';
                
                k_neighbours = str2num(app.NeighboursEditField.Value);
                options = {k_neighbours};
            
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Bayes Classifier') == 1
                classifier = 'bayes';
                
            elseif strcmp(app.ButtonGroup_2.SelectedObject.Text, 'Suport Vector Machine Classifier') == 1
                classifier = 'svm';
                
                c_pot = str2num(app.CostpotEditField.Value);
                g_pot = str2num(app.GammapotEditField.Value);
                
                % KERNEL FUNCTION
                if strcmp(app.KernelFunctionButtonGroup.SelectedObject.Text, 'linear') == 1
                    kernel = 'linear';
                elseif strcmp(app.KernelFunctionButtonGroup.SelectedObject.Text, 'polynomial') == 1
                    kernel = 'polynomial';
                elseif strcmp(app.KernelFunctionButtonGroup.SelectedObject.Text, 'rbf gaussian') == 1
                    kernel = 'rbf';
                elseif strcmp(app.KernelFunctionButtonGroup.SelectedObject.Text, 'sigmoidal') == 1
                    kernel = 'mysigmoid';
                end
                
                options = {c_pot, g_pot, kernel};
            end
            
            % RUN FUNCTIONS:
            
            analyse_data(scenario, pca_rule, corr_check, corr_threshold, kw_check, kw_threshold);
            
            if strcmp(classifier, 'fld') == 1 || strcmp(classifier, 'mdc') == 1
                stats = main_binary(scenario, feature_reduction, classifier, n_runs, trn_ratio);
            elseif strcmp(classifier, 'knn') == 1 || strcmp(classifier, 'bayes') == 1 || strcmp(classifier, 'svm') == 1
                stats = main_multi(scenario, feature_reduction, classifier, n_runs, trn_ratio, options);
            end
            
            app.SpecResult.Text = sprintf('%.4f',stats.mean_specificity);
            app.SensResult.Text = sprintf('%.4f',stats.mean_sensitivity);
            app.AccResult.Text = sprintf('%.4f',stats.mean_accuracy);
            app.ErrorResult.Text = sprintf('%.4f',stats.mean_error);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectDataSetButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectDataSetButtonGroup.Title = 'Select Data Set';
            app.ScenarioAPatientInfoButton = uiradiobutton(app.SelectDataSetButtonGroup);
            app.ScenarioAPatientInfoButton.Text = 'Scenario A (Patient Info)';
            app.ScenarioAPatientInfoButton.Value = true;
            app.ScenarioBPatientInfoAdditionalInfoButton = uiradiobutton(app.SelectDataSetButtonGroup);
            app.ScenarioBPatientInfoAdditionalInfoButton.Text = 'Scenario B (Patient Info + Additional Info)';
            app.ScenarioCPatientInfoAdditionalInfoButton = uiradiobutton(app.SelectDataSetButtonGroup);
            app.ScenarioCPatientInfoAdditionalInfoButton.Text = 'Scenario C (Patient Info + Additional Info)';
            app.ExperimentalConfigurationPanel = uipanel(app.UIFigure);
            app.ExperimentalConfigurationPanel.Title = 'Experimental Configuration';
            app.NumRunsLabel = uilabel(app.ExperimentalConfigurationPanel);
            app.NumRunsLabel.Text = 'Num. Runs:';
            app.NumRunsEditField = uieditfield(app.ExperimentalConfigurationPanel, 'text');
            app.TrainingRatioEditFieldLabel = uilabel(app.ExperimentalConfigurationPanel);
            app.TrainingRatioEditFieldLabel.Text = 'Training Ratio:';
            app.TrainingRatioEditField = uieditfield(app.ExperimentalConfigurationPanel, 'text');
            app.FeatureSelectionPanel = uipanel(app.UIFigure);
            app.FeatureSelectionPanel.Title = 'Feature Selection';
            app.KruskalWalliswThresholdCheckBox = uicheckbox(app.FeatureSelectionPanel);
            app.KruskalWalliswThresholdCheckBox.Text = 'Kruskal Wallis w/ Threshold';
            app.ThresholdEditFieldLabel = uilabel(app.FeatureSelectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.FeatureSelectionPanel, 'text');
            app.RemoveRedundancyCheckBox = uicheckbox(app.FeatureSelectionPanel);
            app.RemoveRedundancyCheckBox.Text = 'Remove Redundancy ';
            app.ThresholdEditField_3Label = uilabel(app.FeatureSelectionPanel);
            app.ThresholdEditField_3Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.FeatureSelectionPanel, 'text');
            app.FeatureReductionPanel = uipanel(app.UIFigure);
            app.FeatureReductionPanel.Title = 'Feature Reduction';
            app.ButtonGroup = uibuttongroup(app.FeatureReductionPanel);
            app.PCAeigenvalueswkaisercriteriaButton = uiradiobutton(app.ButtonGroup);
            app.PCAeigenvalueswkaisercriteriaButton.Text = 'PCA (eigen values w/ kaiser criteria)';
            app.PCAeigenvalueswkaisercriteriaButton.Value = true;
            app.PCAeigenvalueswscreecriteriaButton = uiradiobutton(app.ButtonGroup);
            app.PCAeigenvalueswscreecriteriaButton.Text = 'PCA (eigen values w/ scree criteria)';
            app.LDAButton = uiradiobutton(app.ButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.NoneButton = uiradiobutton(app.ButtonGroup);
            app.NoneButton.Text = 'None';
            app.ClassifierPanel = uipanel(app.UIFigure);
            app.ClassifierPanel.Title = 'Classifier';
            app.ButtonGroup_2 = uibuttongroup(app.ClassifierPanel);
            app.EuclideanMinimumDistanceClassifierButton = uiradiobutton(app.ButtonGroup_2);
            app.EuclideanMinimumDistanceClassifierButton.Text = 'Euclidean Minimum Distance Classifier';
            app.EuclideanMinimumDistanceClassifierButton.Value = true;
            app.FisherLDAClassifierButton = uiradiobutton(app.ButtonGroup_2);
            app.FisherLDAClassifierButton.Text = 'Fisher LDA Classifier';
            app.KNearestNeighborClassifierButton = uiradiobutton(app.ButtonGroup_2);
            app.KNearestNeighborClassifierButton.Text = 'K Nearest Neighbor Classifier';
            app.SuportVectorMachineClassifierButton = uiradiobutton(app.ButtonGroup_2);
            app.SuportVectorMachineClassifierButton.Text = 'Suport Vector Machine Classifier';
            app.BayesClassifierButton = uiradiobutton(app.ButtonGroup_2);
            app.BayesClassifierButton.Text = 'Bayes Classifier';
            app.KernelFunctionButtonGroup = uibuttongroup(app.ClassifierPanel);
            app.KernelFunctionButtonGroup.Title = 'Kernel Function';
            app.linearButton = uiradiobutton(app.KernelFunctionButtonGroup);
            app.linearButton.Text = 'linear';
            app.linearButton.Value = true;
            app.polynomialButton = uiradiobutton(app.KernelFunctionButtonGroup);
            app.polynomialButton.Text = 'polynomial';
            app.rbfgaussianButton = uiradiobutton(app.KernelFunctionButtonGroup);
            app.rbfgaussianButton.Text = 'rbf gaussian';
            app.sigmoidalButton = uiradiobutton(app.KernelFunctionButtonGroup);
            app.sigmoidalButton.Text = 'sigmoidal';
            app.KernelFunctionButtonGroup_3 = uibuttongroup(app.ClassifierPanel);
            app.KernelFunctionButtonGroup_3.Title = 'Kernel Function';
            app.linearButton_3 = uiradiobutton(app.KernelFunctionButtonGroup_3);
            app.linearButton_3.Text = 'linear';
            app.linearButton_3.Value = true;
            app.polynomialButton_3 = uiradiobutton(app.KernelFunctionButtonGroup_3);
            app.polynomialButton_3.Text = 'polynomial';
            app.rbfgaussianButton_3 = uiradiobutton(app.KernelFunctionButtonGroup_3);
            app.rbfgaussianButton_3.Text = 'rbf gaussian';
            app.sigmoidalButton_3 = uiradiobutton(app.KernelFunctionButtonGroup_3);
            app.sigmoidalButton_3.Text = 'sigmoidal';
            app.ParametersButtonGroup = uibuttongroup(app.ClassifierPanel);
            app.ParametersButtonGroup.Title = 'Parameters';
            app.CostpotLabel = uilabel(app.ParametersButtonGroup);
            app.CostpotLabel.Text = 'Cost (pot.):';
            app.CostpotEditField = uieditfield(app.ParametersButtonGroup, 'text');
            app.GammapotLabel = uilabel(app.ParametersButtonGroup);
            app.GammapotLabel.Text = 'Gamma (pot.):';
            app.GammapotEditField = uieditfield(app.ParametersButtonGroup, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NeighboursLabel = uilabel(app.UIFigure);
            app.NeighboursLabel.Text = 'Neighbours:';
            app.NeighboursEditField = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.Covid19PredictionLabel = uilabel(app.Panel);
            app.Covid19PredictionLabel.Text = 'Covid-19 Prediction';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.AvgSpecificityLabel = uilabel(app.UIFigure);
            app.AvgSpecificityLabel.Text = 'Avg. Specificity';
            app.AvgSensibilityLabel = uilabel(app.UIFigure);
            app.AvgSensibilityLabel.Text = 'Avg. Sensibility';
            app.AvgAccuracyLabel = uilabel(app.UIFigure);
            app.AvgAccuracyLabel.Text = 'Avg. Accuracy';
            app.AvgErrorLabel = uilabel(app.UIFigure);
            app.AvgErrorLabel.Text = 'Avg. Error';
            app.AUCLabel = uilabel(app.UIFigure);
            app.AUCLabel.Text = 'AUC ???';
            app.SpecResult = uilabel(app.UIFigure);
            app.SpecResult.Text = '0';
            app.SensResult = uilabel(app.UIFigure);
            app.SensResult.Text = '0';
            app.AccResult = uilabel(app.UIFigure);
            app.AccResult.Text = '0';
            app.ErrorResult = uilabel(app.UIFigure);
            app.ErrorResult.Text = '0';
            app.AUCResult = uilabel(app.UIFigure);
            app.AUCResult.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end